classdef Room_Reverb_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KHzEditField_3                matlab.ui.control.NumericEditField
        KHzEditField_3Label           matlab.ui.control.Label
        KHzEditField_2                matlab.ui.control.NumericEditField
        KHzEditField_2Label           matlab.ui.control.Label
        KHzEditField                  matlab.ui.control.NumericEditField
        KHzEditFieldLabel             matlab.ui.control.Label
        HzEditField_3                 matlab.ui.control.NumericEditField
        HzEditField_3Label            matlab.ui.control.Label
        HzEditField_2                 matlab.ui.control.NumericEditField
        HzEditField_2Label            matlab.ui.control.Label
        HzEditField                   matlab.ui.control.NumericEditField
        HzEditFieldLabel              matlab.ui.control.Label
        CeelingmaterialDropDown       matlab.ui.control.DropDown
        CeelingmaterialDropDownLabel  matlab.ui.control.Label
        FloormaterialDropDown         matlab.ui.control.DropDown
        FloormaterialDropDownLabel    matlab.ui.control.Label
        WallmaterialDropDown          matlab.ui.control.DropDown
        WallmaterialDropDownLabel     matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Lvalue = app.LengthEditField.Value;
            Hvalue = app.HeightEditField.Value;
            Wvalue = app.WidthEditField.Value;

            Volume = Wvalue*Hvalue*Lvalue;

            Walls = ((Lvalue*Hvalue)*2)+((Wvalue*Hvalue)*2);
            Floor = Wvalue * Lvalue;
            Ceeling = Wvalue * Lvalue;

            MaterialWall = str2double(app.WallmaterialDropDown.Value);
            MaterialFloor = str2double(app.FloormaterialDropDown.Value);
            MaterialCeeling = str2double(app.CeelingmaterialDropDown.Value);

            Carpet = [0.1, 0.15, 0.25, 0.30, 0.30, 0.30];
            Plaster = [0.02, 0.02, 0.02, 0.02, 0.02, 0.02];
            CeelingTiles = [0.45, 0.7, 0.8, 0.8, 0.65, 0.45];
            Rockwoll = [0.15, 0.6, 0.9, 0.9, 0.9, 0.85];

            Matrix = [Carpet; Plaster; CeelingTiles; Rockwoll];

            WallCoefficients = Matrix(MaterialWall,:);
            FloorCoefficients = Matrix(MaterialFloor,:);
            CeelingCoefficients = Matrix(MaterialCeeling,:);

            AbsorbWall = WallCoefficients * Walls;
            AbsorbFloor = FloorCoefficients * Floor;
            AbsorbCeeling = CeelingCoefficients * Ceeling;

            AbsorbTotal = AbsorbWall+AbsorbFloor+AbsorbCeeling;

            Sabine = (0.161 * Volume) ./ AbsorbTotal;

            app.HzEditField.Value = Sabine(1);
            app.HzEditField_2.Value = Sabine(2);
            app.HzEditField_3.Value = Sabine(3);
            app.KHzEditField.Value = Sabine(4);
            app.KHzEditField_2.Value = Sabine(5);
            app.KHzEditField_3.Value = Sabine(6);

 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'prism');
            app.UIFigure.Name = 'MATLAB App';
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WallmaterialDropDownLabel = uilabel(app.UIFigure);
            app.WallmaterialDropDownLabel.Text = 'Wall material';
            app.WallmaterialDropDown = uidropdown(app.UIFigure);
            app.WallmaterialDropDown.Items = {'Carpet', 'Plaster', 'CeelingTiles', 'Rockwoll'};
            app.WallmaterialDropDown.ItemsData = {'1', '2', '3', '4'};
            app.WallmaterialDropDown.Value = '1';
            app.FloormaterialDropDownLabel = uilabel(app.UIFigure);
            app.FloormaterialDropDownLabel.Text = 'Floor material';
            app.FloormaterialDropDown = uidropdown(app.UIFigure);
            app.FloormaterialDropDown.Items = {'Carpet', 'Plaster', 'CeelingTiles', 'Rockwoll'};
            app.FloormaterialDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FloormaterialDropDown.Value = '1';
            app.CeelingmaterialDropDownLabel = uilabel(app.UIFigure);
            app.CeelingmaterialDropDownLabel.Text = 'Ceeling material';
            app.CeelingmaterialDropDown = uidropdown(app.UIFigure);
            app.CeelingmaterialDropDown.Items = {'Carpet', 'Plaster', 'CeelingTiles', 'Rockwoll'};
            app.CeelingmaterialDropDown.ItemsData = {'1', '2', '3', '4'};
            app.CeelingmaterialDropDown.Value = '1';
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '125Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_2Label = uilabel(app.UIFigure);
            app.HzEditField_2Label.Text = '250Hz';
            app.HzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3Label = uilabel(app.UIFigure);
            app.HzEditField_3Label.Text = '500Hz';
            app.HzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.KHzEditFieldLabel = uilabel(app.UIFigure);
            app.KHzEditFieldLabel.Text = '1KHz';
            app.KHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.KHzEditField_2Label = uilabel(app.UIFigure);
            app.KHzEditField_2Label.Text = '2KHz';
            app.KHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.KHzEditField_3Label = uilabel(app.UIFigure);
            app.KHzEditField_3Label.Text = '4KHz';
            app.KHzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Room_Reverb_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end